classdef Monte_Carlo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        ScatterPacketsTab              matlab.ui.container.Tab
        MCInputsPanel                  matlab.ui.container.Panel
        Absorptioncoefficientam1Label  matlab.ui.control.Label
        Absorptioncoefficientam1EditField  matlab.ui.control.NumericEditField
        Scatteringcoefficientbm1EditFieldLabel  matlab.ui.control.Label
        Scatteringcoefficientbm1EditField  matlab.ui.control.NumericEditField
        PacketsperloopEditFieldLabel   matlab.ui.control.Label
        PacketsperloopEditField        matlab.ui.control.NumericEditField
        ofloopsSpinnerLabel            matlab.ui.control.Label
        ofloopsSpinner                 matlab.ui.control.Spinner
        MaxofscatteringeventsSpinnerLabel  matlab.ui.control.Label
        MaxofscatteringeventsSpinner   matlab.ui.control.Spinner
        PathlengthmaxmEditFieldLabel   matlab.ui.control.Label
        PathlengthmaxmEditField        matlab.ui.control.NumericEditField
        LambertiansemiangledegreesEditFieldLabel  matlab.ui.control.Label
        LambertiansemiangledegreesEditField  matlab.ui.control.NumericEditField
        PixelpositionsxywhmTextAreaLabel  matlab.ui.control.Label
        PixelpositionsxywhmTextArea    matlab.ui.control.TextArea
        RunScatteringProcessButton     matlab.ui.control.Button
        SourceemissionprofileDropDownLabel  matlab.ui.control.Label
        SourceemissionprofileDropDown  matlab.ui.control.DropDown
        AnalysisTab                    matlab.ui.container.Tab
    end

    
    properties (Access = public)
        a = 0.00922; % A coefficient (m^-1)
        b = 0.179; % B coefficient (m^-1)
        packets = 1e6; % # packets launched per loop
        numloops = 1; % Number of loops
        max_scatter = 10; % Maximum # of scattering events
        pmax = 10; % Maximum photon path length (m)
        sourcetype = "Lambertian"; % Source emission profile type
        semiangle = 60; % Lambertian semiangle (degrees)
        pixels = [-0.5e-3, -0.5e-3, 1e-3, 1e-3]; % Pixel location(s) (x,y,w,h), (m)
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Absorptioncoefficientam1EditField
        function Absorptioncoefficientam1EditFieldValueChanged(app, event)
            value = app.Absorptioncoefficientam1EditField.Value;
            app.a = value;
        end

        % Value changed function: ofloopsSpinner
        function ofloopsSpinnerValueChanged(app, event)
            value = app.ofloopsSpinner.Value;
            app.numloops = value;
        end

        % Value changed function: Scatteringcoefficientbm1EditField
        function Scatteringcoefficientbm1EditFieldValueChanged(app, event)
            value = app.Scatteringcoefficientbm1EditField.Value;
            app.b = value;
        end

        % Value changed function: PacketsperloopEditField
        function PacketsperloopEditFieldValueChanged(app, event)
            value = app.PacketsperloopEditField.Value;
            app.packets = value;
        end

        % Value changed function: MaxofscatteringeventsSpinner
        function MaxofscatteringeventsSpinnerValueChanged(app, event)
            value = app.MaxofscatteringeventsSpinner.Value;
            app.max_scatter = value;
        end

        % Value changed function: PathlengthmaxmEditField
        function PathlengthmaxmEditFieldValueChanged(app, event)
            value = app.PathlengthmaxmEditField.Value;
            app.pmax = value;
        end

        % Value changed function: SourceemissionprofileDropDown
        function SourceemissionprofileDropDownValueChanged(app, event)
            value = app.SourceemissionprofileDropDown.Value;
            
            if value == "Lambertian"
                app.sourcetype = "Lambertian";
                app.LambertiansemiangledegreesEditField.Enable = 'on';
            elseif value == "Custom"
                app.sourcetype = "Custom";
                app.LambertiansemiangledegreesEditField.Enable = 'off';
            elseif value == "Idealised"
                app.sourcetype = "Ideal";
                app.LambertiansemiangledegreesEditField.Enable = 'off';
            end
        end

        % Value changed function: 
        % LambertiansemiangledegreesEditField
        function LambertiansemiangledegreesEditFieldValueChanged(app, event)
            value = app.LambertiansemiangledegreesEditField.Value;
            app.semiangle = value;
        end

        % Value changed function: PixelpositionsxywhmTextArea
        function PixelpositionsxywhmTextAreaValueChanged(app, event)
            value = app.PixelpositionsxywhmTextArea.Value;
            app.pixels = value;
        end

        % Button pushed function: RunScatteringProcessButton
        function RunScatteringProcessButtonPushed(app, event)
            MC_scatter(app.a,app.b,app.packets,app.numloops,...
                app.max_scatter,app.pmax,app.sourcetype,...
                app.semiangle,app.pixels);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ScatterPacketsTab = uitab(app.TabGroup);
            app.ScatterPacketsTab.Title = 'Scatter Packets';
            app.MCInputsPanel = uipanel(app.ScatterPacketsTab);
            app.MCInputsPanel.Title = 'MC Inputs';
            app.Absorptioncoefficientam1Label = uilabel(app.MCInputsPanel);
            app.Absorptioncoefficientam1Label.Text = 'Absorption coefficient, a, (m^-1)';
            app.Absorptioncoefficientam1EditField = uieditfield(app.MCInputsPanel, 'numeric');
            app.Absorptioncoefficientam1EditField.Limits = [0 Inf];
            app.Absorptioncoefficientam1EditField.ValueChangedFcn = createCallbackFcn(app, @Absorptioncoefficientam1EditFieldValueChanged, true);
            app.Absorptioncoefficientam1EditField.Value = 0.00922;
            app.Scatteringcoefficientbm1EditFieldLabel = uilabel(app.MCInputsPanel);
            app.Scatteringcoefficientbm1EditFieldLabel.Text = 'Scattering coefficient, b, (m^-1)';
            app.Scatteringcoefficientbm1EditField = uieditfield(app.MCInputsPanel, 'numeric');
            app.Scatteringcoefficientbm1EditField.Limits = [0 Inf];
            app.Scatteringcoefficientbm1EditField.ValueChangedFcn = createCallbackFcn(app, @Scatteringcoefficientbm1EditFieldValueChanged, true);
            app.Scatteringcoefficientbm1EditField.Value = 0.179;
            app.PacketsperloopEditFieldLabel = uilabel(app.MCInputsPanel);
            app.PacketsperloopEditFieldLabel.Text = '# Packets per loop';
            app.PacketsperloopEditField = uieditfield(app.MCInputsPanel, 'numeric');
            app.PacketsperloopEditField.Limits = [1 Inf];
            app.PacketsperloopEditField.ValueChangedFcn = createCallbackFcn(app, @PacketsperloopEditFieldValueChanged, true);
            app.PacketsperloopEditField.Value = 1000000;
            app.ofloopsSpinnerLabel = uilabel(app.MCInputsPanel);
            app.ofloopsSpinnerLabel.Text = '# of loops';
            app.ofloopsSpinner = uispinner(app.MCInputsPanel);
            app.ofloopsSpinner.Limits = [1 Inf];
            app.ofloopsSpinner.ValueChangedFcn = createCallbackFcn(app, @ofloopsSpinnerValueChanged, true);
            app.ofloopsSpinner.Value = 1;
            app.MaxofscatteringeventsSpinnerLabel = uilabel(app.MCInputsPanel);
            app.MaxofscatteringeventsSpinnerLabel.Text = 'Max. # of scattering events';
            app.MaxofscatteringeventsSpinner = uispinner(app.MCInputsPanel);
            app.MaxofscatteringeventsSpinner.ValueChangedFcn = createCallbackFcn(app, @MaxofscatteringeventsSpinnerValueChanged, true);
            app.MaxofscatteringeventsSpinner.Value = 10;
            app.PathlengthmaxmEditFieldLabel = uilabel(app.MCInputsPanel);
            app.PathlengthmaxmEditFieldLabel.Text = 'Path length max. (m)';
            app.PathlengthmaxmEditField = uieditfield(app.MCInputsPanel, 'numeric');
            app.PathlengthmaxmEditField.ValueChangedFcn = createCallbackFcn(app, @PathlengthmaxmEditFieldValueChanged, true);
            app.PathlengthmaxmEditField.Value = 10;
            app.LambertiansemiangledegreesEditFieldLabel = uilabel(app.ScatterPacketsTab);
            app.LambertiansemiangledegreesEditFieldLabel.Text = 'Lambertian semiangle (degrees)';
            app.LambertiansemiangledegreesEditField = uieditfield(app.ScatterPacketsTab, 'numeric');
            app.LambertiansemiangledegreesEditField.ValueChangedFcn = createCallbackFcn(app, @LambertiansemiangledegreesEditFieldValueChanged, true);
            app.PixelpositionsxywhmTextAreaLabel = uilabel(app.ScatterPacketsTab);
            app.PixelpositionsxywhmTextAreaLabel.Text = 'Pixel positions (x,y,w,h), (m)';
            app.PixelpositionsxywhmTextArea = uitextarea(app.ScatterPacketsTab);
            app.PixelpositionsxywhmTextArea.ValueChangedFcn = createCallbackFcn(app, @PixelpositionsxywhmTextAreaValueChanged, true);
            app.PixelpositionsxywhmTextArea.Value = {'[-0.5e-3, -0.5e-3, 1e-3, 1e-3...'; ']'};
            app.RunScatteringProcessButton = uibutton(app.ScatterPacketsTab, 'push');
            app.RunScatteringProcessButton.ButtonPushedFcn = createCallbackFcn(app, @RunScatteringProcessButtonPushed, true);
            app.RunScatteringProcessButton.Text = 'Run Scattering Process';
            app.SourceemissionprofileDropDownLabel = uilabel(app.ScatterPacketsTab);
            app.SourceemissionprofileDropDownLabel.Text = 'Source emission profile';
            app.SourceemissionprofileDropDown = uidropdown(app.ScatterPacketsTab);
            app.SourceemissionprofileDropDown.Items = {'Idealised', 'Lambertian', 'Custom'};
            app.SourceemissionprofileDropDown.ValueChangedFcn = createCallbackFcn(app, @SourceemissionprofileDropDownValueChanged, true);
            app.SourceemissionprofileDropDown.Value = 'Lambertian';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Monte_Carlo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end